classdef App7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        DetectButton                matlab.ui.control.Button
        PowerLamp                   matlab.ui.control.Lamp
        PowerLampLabel              matlab.ui.control.Label
        ZLabel                      matlab.ui.control.Label
        YLabel                      matlab.ui.control.Label
        XLabel                      matlab.ui.control.Label
        ZGauge                      matlab.ui.control.LinearGauge
        YGauge                      matlab.ui.control.LinearGauge
        XGauge                      matlab.ui.control.LinearGauge
        ZSpinner                    matlab.ui.control.Spinner
        YSpinner                    matlab.ui.control.Spinner
        XSpinner                    matlab.ui.control.Spinner
        AboutButton                 matlab.ui.control.Button
        Label_UtrasoundSystem       matlab.ui.control.Label
        Label_WaterSystem           matlab.ui.control.Label
        Label_VideoSystem           matlab.ui.control.Label
        UtrasoundsystemButtonGroup  matlab.ui.container.ButtonGroup
        OnButton_UtrasoundSystem    matlab.ui.control.RadioButton
        OffButton_UtrasoundSystem   matlab.ui.control.RadioButton
        WatersystemButtonGroup      matlab.ui.container.ButtonGroup
        OnButton_WaterSystem        matlab.ui.control.RadioButton
        OffButton_WaterSystem       matlab.ui.control.RadioButton
        VideosystemButtonGroup      matlab.ui.container.ButtonGroup
        OnButton_VideoSystem        matlab.ui.control.RadioButton
        OffButton_VideoSystem       matlab.ui.control.RadioButton
        uipanel1                    matlab.ui.container.Panel
        pushbuttonload              matlab.ui.control.Button
        axes_model                  matlab.ui.control.UIAxes
        UIAxes2_3                   matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        cam
        frame
        x
 % Description
    end
    
    methods (Access = private)
        function output_text = dataCursorText(app, handles, event_obj)
            
             postarget = get(event_obj,'Target')
            
             pos = get(event_obj, 'Position');%得到离特征点最近的点；
            
            output_text = pos;
            htable = findobj('Tag','uitablecurrent');%uitableCurrentFP);
            set(htable,'data',pos);
        end
        
        function popupmenu2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitableFP.ColumnFormat = {[] [] [] []};
            app.uitablecurrent.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.uitablecurrent.ColumnFormat = {[] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function unify_OpeningFcn(app, varargin)
            LEDOFF(app)
            im1=imread('left.jpg')
            imshow(im1,'Parent',app.UIAxes2)
            im2=imread('middle.jpg')
            imshow(im2,'Parent',app.UIAxes2_2)
            im3=imread('right.jpg')
            imshow(im3,'Parent',app.UIAxes2_3)


            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            % Choose default command line output for unify
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function
        function pushbutonselect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutonselect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dcm_obj = datacursormode(handles.figure1);
            
            set(dcm_obj,'SnapToDataVertex','on','Enable','on','UpdateFcn', @app.dataCursorText);
        end

        % Callback function
        function pushbuttoninsert_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttoninsert (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hmenu = findobj('Tag','popupmenunum');%获取句柄的另一种方法
            FPnum = get(hmenu,'value'); %表示 特征点的序号
            htable = findobj('Tag','uitablecurrent');
            pos = get(htable,'data'); %得到特征点的坐标
            
            plot3(pos(1),pos(2),pos(3),'r.','MarkerFaceColor','r', 'MarkerSize',20 );
            
            text(pos(1)+5,pos(2)+5,pos(3)+5,num2str(FPnum));
            pos = [FPnum pos]; %给特征点加上顺序号
            disp (pos);
            htableFP = findobj('Tag','uitableFP');
            FP_pos = getappdata(gcf,'FP_pos');
            FP_pos = [FP_pos;pos];
            set(htableFP,'data',FP_pos);
            setappdata(gcf,'FP_pos',FP_pos);
        end

        % Button pushed function: pushbuttonload
        function pushbuttonload_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonload (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % global flag;
            [fname,pname]=uigetfile('*.obj','Open Stand Model');%通过uigetfile函数获取文件名和路径
            filename=strcat(pname,fname);%获取文件的绝对路径
            data=ReadObj_h(filename);
            setappdata(gcf,'fname',fname); %setappdata(h,'name',value)
            setappdata(gcf,'pname',pname);%当前路径
            
            facecolor = ...
                [0.8,0.8,0.8;
                0.8,0.8,0.8;
                0.8,0.8,0.8;
                0.8,0.8,0.8;
                0.8,0.8,0.8];
            
            axes(handles.axes_model);%获取坐标轴的句柄handles.axes_model
            
            FPnum = 0; %代表第几个特征点的个数。
            setappdata(gcf,'FPnum',FPnum);  %给此变量赋值
            FPpos=[];
            setappdata(gcf,'FPpos',FPpos);
            global faces;
            global vertexes;
            faces = [];
            vertexes = [];
            faces=data.faces;
            vertexes=data.vertexes;
            vertexes=vertexes';%矩阵转置,每一行表示一个点的坐标
            faces=faces(1:3,:);
            faces=faces';
            setappdata(gcf,'vertexes',vertexes);
            setappdata(gcf,'faces',faces);
            
            
            %%
            light('Color','w','Position',[0 -1 0],'Style','infinite');hidden off;
            
            trisurf(faces,vertexes(:,1),vertexes(:,2),vertexes(:,3), 'FaceColor', '#808080', 'EdgeColor', 'none', 'FaceAlpha', 0.99);
            
            hold on;
            light('Color','w','Position',[1 -2 1],'Style','infinite');
            light('Color','w','Position',[-1  2 -0.5],'Style','infinite');
            
            xlabel('X axis');
            ylabel('Y axis');
            zlabel('Z axis');
            view(0,90);
            axis tight;
            grid off;
            hold on;
            title([fname]);
            rotate3d on;
        end

        % Callback function
        function pushbuttonsfp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonsfp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            FP_pos =  getappdata(gcf,'FP_pos');
            [m,n]=size(FP_pos);
            for i=1:m
                for j=1:n
                    cent(i,j)=FP_pos(i,j);
                end
            end
            save('marks','cent')
        end

        % Callback function
        function rotate_off_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rotate_off (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            rotate3d off;
        end

        % Callback function
        function rotate_on_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rotate_on (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            rotate3d on;
        end

        % Key press function: figure1
        function figure1KeyPress(app, event)
            key = event.Key;

            switch key
                case 'p'
                    app.PowerLamp.Color='#00FF00';
                    addpath("D:\Thesis");
                    LED1(app);

                case 'v'
                    if app.PowerLamp.Color==[0,1,0]
                        LED2(app);
                        app.cam=webcam(2);
                        app.frame=snapshot(app.cam);
                        im=image(app.UIAxes,zeros(size(app.frame),'uint8'));
                        axis(app.UIAxes,'image')
                        preview(app.cam,im)
                        app.OnButton_VideoSystem.Value=true;
                        app.Label_VideoSystem.Text='Video system is ready';
                    end
                    if app.PowerLamp.Color==[0.5,0.5,0.5]
                        LED2OFF(app)
                    end

                case 'u'
                    if app.PowerLamp.Color==[0,1,0]
                        LED4(app)
                        app.OnButton_UtrasoundSystem.Value=true;
                        app.Label_UtrasoundSystem.Text='Utrasound system is ready';
                    end
                    if app.PowerLamp.Color==[0.5,0.5,0.5]
                        LED4OFF(app)
                    end

                case 'w'
                    if app.PowerLamp.Color==[0,1,0]
                        LED3(app)
                        app.OnButton_WaterSystem.Value=true;
                        app.Label_WaterSystem.Text='Water system is ready';
                    end
                    if app.PowerLamp.Color==[0.5,0.5,0.5]
                        LED3OFF(app)
                    end

                case '1'
                    app.PowerLamp.Color=[0.5 0.5 0.5];
                    LEDOFF(app)
                    im=imread('File.jpg');
                    imshow(im,'Parent',app.UIAxes);

                case '2'
                    app.OffButton_VideoSystem.Value=true;
                    app.Label_VideoSystem.Text='Video system is off';
                    LED2OFF(app.frame)
                    im=imread('File.jpg');
                    imshow(im,'Parent',app.UIAxes);

                case '3'
                    LED3OFF(app)
                    app.OffButton_WaterSystem.Value=true;
                    app.Label_WaterSystem.Text='Water system is off';

                case '4'
                    LED4OFF(app)
                    app.OffButton_UtrasoundSystem.Value=true;
                    app.Label_UtrasoundSystem.Text='Utrasound system is off';

                case 'o'
                    im=imread('File.jpg');
                    imshow(im,'Parent',app.UIAxes);
                    LEDOFF(app)
                    delete(app.UIFigure)
                    
            end

        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msgbox(['Press p to turn on the power. ' ...
                'Press v to start video system. ' ...
                'Press w to start water system. ' ...
                'Press u to start utrasound system. ' ...
                'Press 1 to turn off the power. ' ...
                'Press 2 to turn off video system. ' ...
                'Press 3 to turn off water system. ' ...
                'Press 4 to turn off utrasound system. ' ...
                'Press o to turn off the whole system and close this window'],'Help','help')
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.XGauge.Value=changingValue;
        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.YGauge.Value=changingValue;
        end

        % Value changing function: ZSpinner
        function ZSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.ZGauge.Value=changingValue;
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            addpath("D:\Thesis\toothdata")
            load trainedYOLOv2Detector.mat
            load gTruth.mat
            ClassesNames = categorical(["Left","Front","Right"]')
            
            app.x=videoinput("winvideo","2")
            while(true)
                img=getsnapshot(app.x);
                img=imresize(img,[448,448])
                [bboxes,scores,labels]=detect(detector,img)
                [~,ind] = ismember(labels,gTruth.LabelDefinitions.Name);
                cmaps = [
                    255 000 000;         %黄色
                    000 255 000;
                    000 000 255%红色
                    ];
                if isempty(labels)
                    imshow(img,'Parent',app.UIAxes)
                    title("No object")
                    
                    %         istr=num2str(i)
                    %         begin=strcat('A',istr)
                    %         %final=strcat(istr,'D')
                    %         xlswrite('bboxes.xlsx',bboxes,'sheet2',begin);


                    %fprintf(bboxes[1])
                else
                    detectedImg = insertObjectAnnotation(img, 'Rectangle', bboxes, cellstr(labels),...
                        'Color',cmaps(ind,:));
                    imshow(detectedImg,'Parent',app.UIAxes)
                end


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'unify';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1KeyPress, true);
            app.figure1.Tag = 'figure1';
            app.UIAxes = uiaxes(app.figure1);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.figure1);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.figure1);
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_3 = uiaxes(app.figure1);
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '3D tooth model';
            app.uipanel1.Tag = 'uipanel1';
            app.axes_model = uiaxes(app.uipanel1);
            app.axes_model.XTick = [];
            app.axes_model.YTick = [];
            app.axes_model.Tag = 'axes_model';
            app.pushbuttonload = uibutton(app.uipanel1, 'push');
            app.pushbuttonload.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonload_Callback, true);
            app.pushbuttonload.Tag = 'pushbuttonload';
            app.pushbuttonload.Text = 'load';
            app.VideosystemButtonGroup = uibuttongroup(app.figure1);
            app.VideosystemButtonGroup.Title = 'Video system';
            app.OffButton_VideoSystem = uiradiobutton(app.VideosystemButtonGroup);
            app.OffButton_VideoSystem.Text = 'Off';
            app.OffButton_VideoSystem.Value = true;
            app.OnButton_VideoSystem = uiradiobutton(app.VideosystemButtonGroup);
            app.OnButton_VideoSystem.Text = 'On';
            app.WatersystemButtonGroup = uibuttongroup(app.figure1);
            app.WatersystemButtonGroup.Title = 'Water system';
            app.OffButton_WaterSystem = uiradiobutton(app.WatersystemButtonGroup);
            app.OffButton_WaterSystem.Text = 'Off';
            app.OffButton_WaterSystem.Value = true;
            app.OnButton_WaterSystem = uiradiobutton(app.WatersystemButtonGroup);
            app.OnButton_WaterSystem.Text = 'On';
            app.UtrasoundsystemButtonGroup = uibuttongroup(app.figure1);
            app.UtrasoundsystemButtonGroup.Title = 'Utrasound system';
            app.OffButton_UtrasoundSystem = uiradiobutton(app.UtrasoundsystemButtonGroup);
            app.OffButton_UtrasoundSystem.Text = 'Off';
            app.OffButton_UtrasoundSystem.Value = true;
            app.OnButton_UtrasoundSystem = uiradiobutton(app.UtrasoundsystemButtonGroup);
            app.OnButton_UtrasoundSystem.Text = 'On';
            app.Label_VideoSystem = uilabel(app.figure1);
            app.Label_VideoSystem.Text = '';
            app.Label_WaterSystem = uilabel(app.figure1);
            app.Label_WaterSystem.Text = '';
            app.Label_UtrasoundSystem = uilabel(app.figure1);
            app.Label_UtrasoundSystem.Text = '';
            app.AboutButton = uibutton(app.figure1, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Tooltip = {'Push this button to know more.'};
            app.AboutButton.Text = 'About';
            app.XSpinner = uispinner(app.figure1);
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.XSpinner.Limits = [0 100];
            app.YSpinner = uispinner(app.figure1);
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @YSpinnerValueChanging, true);
            app.YSpinner.Limits = [0 100];
            app.ZSpinner = uispinner(app.figure1);
            app.ZSpinner.ValueChangingFcn = createCallbackFcn(app, @ZSpinnerValueChanging, true);
            app.ZSpinner.Limits = [0 100];
            app.XGauge = uigauge(app.figure1, 'linear');
            app.YGauge = uigauge(app.figure1, 'linear');
            app.ZGauge = uigauge(app.figure1, 'linear');
            app.XLabel = uilabel(app.figure1);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.figure1);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.figure1);
            app.ZLabel.Text = 'Z';
            app.PowerLampLabel = uilabel(app.figure1);
            app.PowerLampLabel.Text = 'Power';
            app.PowerLamp = uilamp(app.figure1);
            app.DetectButton = uibutton(app.figure1, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App7(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)unify_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end